classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        EditField_11  matlab.ui.control.EditField
        EditField_12  matlab.ui.control.EditField
        EditField_13  matlab.ui.control.EditField
        EditField_14  matlab.ui.control.EditField
        EditField_15  matlab.ui.control.EditField
        EditField_16  matlab.ui.control.EditField
        EditField_17  matlab.ui.control.EditField
        EditField_18  matlab.ui.control.EditField
        EditField_19  matlab.ui.control.EditField
        EditField_21  matlab.ui.control.EditField
        EditField_22  matlab.ui.control.EditField
        EditField_23  matlab.ui.control.EditField
        EditField_24  matlab.ui.control.EditField
        EditField_25  matlab.ui.control.EditField
        EditField_26  matlab.ui.control.EditField
        EditField_27  matlab.ui.control.EditField
        EditField_28  matlab.ui.control.EditField
        EditField_29  matlab.ui.control.EditField
        EditField_31  matlab.ui.control.EditField
        EditField_32  matlab.ui.control.EditField
        EditField_33  matlab.ui.control.EditField
        EditField_34  matlab.ui.control.EditField
        EditField_35  matlab.ui.control.EditField
        EditField_36  matlab.ui.control.EditField
        EditField_37  matlab.ui.control.EditField
        EditField_38  matlab.ui.control.EditField
        EditField_39  matlab.ui.control.EditField
        EditField_41  matlab.ui.control.EditField
        EditField_42  matlab.ui.control.EditField
        EditField_43  matlab.ui.control.EditField
        EditField_44  matlab.ui.control.EditField
        EditField_45  matlab.ui.control.EditField
        EditField_46  matlab.ui.control.EditField
        EditField_47  matlab.ui.control.EditField
        EditField_48  matlab.ui.control.EditField
        EditField_49  matlab.ui.control.EditField
        EditField_51  matlab.ui.control.EditField
        EditField_52  matlab.ui.control.EditField
        EditField_53  matlab.ui.control.EditField
        EditField_54  matlab.ui.control.EditField
        EditField_55  matlab.ui.control.EditField
        EditField_56  matlab.ui.control.EditField
        EditField_57  matlab.ui.control.EditField
        EditField_58  matlab.ui.control.EditField
        EditField_59  matlab.ui.control.EditField
        EditField_61  matlab.ui.control.EditField
        EditField_62  matlab.ui.control.EditField
        EditField_63  matlab.ui.control.EditField
        EditField_64  matlab.ui.control.EditField
        EditField_65  matlab.ui.control.EditField
        EditField_66  matlab.ui.control.EditField
        EditField_67  matlab.ui.control.EditField
        EditField_68  matlab.ui.control.EditField
        EditField_69  matlab.ui.control.EditField
        EditField_71  matlab.ui.control.EditField
        EditField_72  matlab.ui.control.EditField
        EditField_73  matlab.ui.control.EditField
        EditField_74  matlab.ui.control.EditField
        EditField_75  matlab.ui.control.EditField
        EditField_76  matlab.ui.control.EditField
        EditField_77  matlab.ui.control.EditField
        EditField_78  matlab.ui.control.EditField
        EditField_79  matlab.ui.control.EditField
        EditField_81  matlab.ui.control.EditField
        EditField_82  matlab.ui.control.EditField
        EditField_83  matlab.ui.control.EditField
        EditField_84  matlab.ui.control.EditField
        EditField_85  matlab.ui.control.EditField
        EditField_86  matlab.ui.control.EditField
        EditField_87  matlab.ui.control.EditField
        EditField_88  matlab.ui.control.EditField
        EditField_89  matlab.ui.control.EditField
        EditField_91  matlab.ui.control.EditField
        EditField_92  matlab.ui.control.EditField
        EditField_93  matlab.ui.control.EditField
        EditField_94  matlab.ui.control.EditField
        EditField_95  matlab.ui.control.EditField
        EditField_96  matlab.ui.control.EditField
        EditField_97  matlab.ui.control.EditField
        EditField_98  matlab.ui.control.EditField
        EditField_99  matlab.ui.control.EditField
        Button        matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
    end


    methods (Access = private)
    
        
        function [S,eflag] = sudokuEngine(app,B)
            if isequal(size(B),[9,9]) % 9-by-9 clues
                % Convert to 81-by-3
                [SM,SN] = meshgrid(1:9); % make i,j entries
                B = [SN(:),SM(:),B(:)]; % i,j,k rows
                % Now delete zero rows
                [rrem,~] = find(B(:,3) == 0);
                B(rrem,:) = [];
            end
            
            if size(B,2) ~= 3 || length(size(B)) > 2
                error('The input matrix must be N-by-3 or 9-by-9')
            end
            
            if sum([any(B ~= round(B)),any(B < 1),any(B > 9)]) % enforces entries 1-9
                error('Entries must be integers from 1 to 9')
            end
            
            N = 9^3; % number of independent variables in x, a 9-by-9-by-9 array
            M = 4*9^2; % number of constraints, see the construction of Aeq
            Aeq = zeros(M,N); % allocate equality constraint matrix Aeq*x = beq
            beq = ones(M,1); % allocate constant vector beq
            f = (1:N)'; % the objective can be anything, but having nonconstant f can speed the solver
            lb = zeros(9,9,9); % an initial zero array
            ub = lb+1; % upper bound array to give binary variables
            
            counter = 1;
            for j = 1:9 % one in each row
                for k = 1:9
                    Astuff = lb; % clear Astuff
                    Astuff(1:end,j,k) = 1; % one row in Aeq*x = beq
                    Aeq(counter,:) = Astuff(:)'; % put Astuff in a row of Aeq
                    counter = counter + 1;
                end
            end
            
            for i = 1:9 % one in each column
                for k = 1:9
                    Astuff = lb;
                    Astuff(i,1:end,k) = 1;
                    Aeq(counter,:) = Astuff(:)';
                    counter = counter + 1;
                end
            end
            
            for U = 0:3:6 % one in each square
                for V = 0:3:6
                    for k = 1:9
                        Astuff = lb;
                        Astuff(U+(1:3),V+(1:3),k) = 1;
                        Aeq(counter,:) = Astuff(:)';
                        counter = counter + 1;
                    end
                end
            end
            
            for i = 1:9 % one in each depth
                for j = 1:9
                    Astuff = lb;
                    Astuff(i,j,1:end) = 1;
                    Aeq(counter,:) = Astuff(:)';
                    counter = counter + 1;
                end
            end
            
            for i = 1:size(B,1)
                lb(B(i,1),B(i,2),B(i,3)) = 1;
            end
            
            intcon = 1:N;
            
            [x,~,eflag] = intlinprog(f,intcon,[],[],Aeq,beq,lb,ub);
            
            if eflag > 0
                x = reshape(x,9,9,9);
                x = round(x);
                y = ones(size(x));
                for k = 2:9
                    y(:,:,k) = k;
                end
                
                S = x.*y;
                S = sum(S,3);
            else
                S = [];
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for m=1:9
                for n=1:9
                    H_block=eval(['app.EditField_',num2str(m),num2str(n)]);
                    H_block.Value='';
                end
            end
        end

        % Button pushed function: Button
        function Solve_sudoku(app, event)
            M=zeros(9,9);
            for m=1:9
                for n=1:9
                    H_block=eval(['app.EditField_',num2str(m),num2str(n)]);
                    if ~isempty(H_block.Value)
                        M(m,n)=str2double(H_block.Value);
                    end
                end
            end
            idx=find(M);
            [m_sub,n_sub]=ind2sub([9,9],idx);
            B=[m_sub,n_sub,M(idx)];
            [S,eflag] = sudokuEngine(app,B);
            for m=1:9
                for n=1:9
                    H_block=eval(['app.EditField_',num2str(m),num2str(n)]);
                    H_block.Value=num2str(S(m,n));
                end
            end
        end

        % Value changed function: EditField_11, EditField_12, 
        % EditField_13, EditField_14, EditField_15, EditField_16, 
        % EditField_17, EditField_18, EditField_19, EditField_21, 
        % EditField_22, EditField_23, EditField_24, EditField_25, 
        % EditField_26, EditField_27, EditField_28, EditField_29, 
        % EditField_31, EditField_32, EditField_33, EditField_34, 
        % EditField_35, EditField_36, EditField_37, EditField_38, 
        % EditField_39, EditField_41, EditField_42, EditField_43, 
        % EditField_44, EditField_45, EditField_46, EditField_47, 
        % EditField_48, EditField_49, EditField_51, EditField_52, 
        % EditField_53, EditField_54, EditField_55, EditField_56, 
        % EditField_57, EditField_58, EditField_59, EditField_61, 
        % EditField_62, EditField_63, EditField_64, EditField_65, 
        % EditField_66, EditField_67, EditField_68, EditField_69, 
        % EditField_71, EditField_72, EditField_73, EditField_74, 
        % EditField_75, EditField_76, EditField_77, EditField_78, 
        % EditField_79, EditField_81, EditField_82, EditField_83, 
        % EditField_84, EditField_85, EditField_86, EditField_87, 
        % EditField_88, EditField_89, EditField_91, EditField_92, 
        % EditField_93, EditField_94, EditField_95, EditField_96, 
        % EditField_97, EditField_98, EditField_99
        function ValueChanged(app, event)
            value = event.Source.Value;
            if ~ismember(value,{'1','2','3','4','5','6','7','8','9'})
                event.Source.Value='';
            end                
        end

        % Button pushed function: Button_2
        function CleanAll(app, event)
            for m=1:9
                for n=1:9
                    H_block=eval(['app.EditField_',num2str(m),num2str(n)]);
                    H_block.Value='';
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '数独';
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_11.Value = '1';
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_12.Value = '1';
            app.EditField_13 = uieditfield(app.UIFigure, 'text');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_13.Value = '1';
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_14.Value = '1';
            app.EditField_15 = uieditfield(app.UIFigure, 'text');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_15.Value = '1';
            app.EditField_16 = uieditfield(app.UIFigure, 'text');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_16.Value = '1';
            app.EditField_17 = uieditfield(app.UIFigure, 'text');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_17.Value = '1';
            app.EditField_18 = uieditfield(app.UIFigure, 'text');
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_18.Value = '1';
            app.EditField_19 = uieditfield(app.UIFigure, 'text');
            app.EditField_19.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_19.Value = '1';
            app.EditField_21 = uieditfield(app.UIFigure, 'text');
            app.EditField_21.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_21.Value = '1';
            app.EditField_22 = uieditfield(app.UIFigure, 'text');
            app.EditField_22.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_22.Value = '1';
            app.EditField_23 = uieditfield(app.UIFigure, 'text');
            app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_23.Value = '1';
            app.EditField_24 = uieditfield(app.UIFigure, 'text');
            app.EditField_24.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_24.Value = '1';
            app.EditField_25 = uieditfield(app.UIFigure, 'text');
            app.EditField_25.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_25.Value = '1';
            app.EditField_26 = uieditfield(app.UIFigure, 'text');
            app.EditField_26.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_26.Value = '1';
            app.EditField_27 = uieditfield(app.UIFigure, 'text');
            app.EditField_27.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_27.Value = '1';
            app.EditField_28 = uieditfield(app.UIFigure, 'text');
            app.EditField_28.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_28.Value = '1';
            app.EditField_29 = uieditfield(app.UIFigure, 'text');
            app.EditField_29.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_29.Value = '1';
            app.EditField_31 = uieditfield(app.UIFigure, 'text');
            app.EditField_31.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_31.Value = '1';
            app.EditField_32 = uieditfield(app.UIFigure, 'text');
            app.EditField_32.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_32.Value = '1';
            app.EditField_33 = uieditfield(app.UIFigure, 'text');
            app.EditField_33.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_33.Value = '1';
            app.EditField_34 = uieditfield(app.UIFigure, 'text');
            app.EditField_34.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_34.Value = '1';
            app.EditField_35 = uieditfield(app.UIFigure, 'text');
            app.EditField_35.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_35.Value = '1';
            app.EditField_36 = uieditfield(app.UIFigure, 'text');
            app.EditField_36.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_36.Value = '1';
            app.EditField_37 = uieditfield(app.UIFigure, 'text');
            app.EditField_37.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_37.Value = '1';
            app.EditField_38 = uieditfield(app.UIFigure, 'text');
            app.EditField_38.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_38.Value = '1';
            app.EditField_39 = uieditfield(app.UIFigure, 'text');
            app.EditField_39.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_39.Value = '1';
            app.EditField_41 = uieditfield(app.UIFigure, 'text');
            app.EditField_41.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_41.Value = '1';
            app.EditField_42 = uieditfield(app.UIFigure, 'text');
            app.EditField_42.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_42.Value = '1';
            app.EditField_43 = uieditfield(app.UIFigure, 'text');
            app.EditField_43.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_43.Value = '1';
            app.EditField_44 = uieditfield(app.UIFigure, 'text');
            app.EditField_44.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_44.Value = '1';
            app.EditField_45 = uieditfield(app.UIFigure, 'text');
            app.EditField_45.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_45.Value = '1';
            app.EditField_46 = uieditfield(app.UIFigure, 'text');
            app.EditField_46.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_46.Value = '1';
            app.EditField_47 = uieditfield(app.UIFigure, 'text');
            app.EditField_47.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_47.Value = '1';
            app.EditField_48 = uieditfield(app.UIFigure, 'text');
            app.EditField_48.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_48.Value = '1';
            app.EditField_49 = uieditfield(app.UIFigure, 'text');
            app.EditField_49.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_49.Value = '1';
            app.EditField_51 = uieditfield(app.UIFigure, 'text');
            app.EditField_51.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_51.Value = '1';
            app.EditField_52 = uieditfield(app.UIFigure, 'text');
            app.EditField_52.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_52.Value = '1';
            app.EditField_53 = uieditfield(app.UIFigure, 'text');
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_53.Value = '1';
            app.EditField_54 = uieditfield(app.UIFigure, 'text');
            app.EditField_54.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_54.Value = '1';
            app.EditField_55 = uieditfield(app.UIFigure, 'text');
            app.EditField_55.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_55.Value = '1';
            app.EditField_56 = uieditfield(app.UIFigure, 'text');
            app.EditField_56.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_56.Value = '1';
            app.EditField_57 = uieditfield(app.UIFigure, 'text');
            app.EditField_57.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_57.Value = '1';
            app.EditField_58 = uieditfield(app.UIFigure, 'text');
            app.EditField_58.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_58.Value = '1';
            app.EditField_59 = uieditfield(app.UIFigure, 'text');
            app.EditField_59.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_59.Value = '1';
            app.EditField_61 = uieditfield(app.UIFigure, 'text');
            app.EditField_61.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_61.Value = '1';
            app.EditField_62 = uieditfield(app.UIFigure, 'text');
            app.EditField_62.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_62.Value = '1';
            app.EditField_63 = uieditfield(app.UIFigure, 'text');
            app.EditField_63.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_63.Value = '1';
            app.EditField_64 = uieditfield(app.UIFigure, 'text');
            app.EditField_64.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_64.Value = '1';
            app.EditField_65 = uieditfield(app.UIFigure, 'text');
            app.EditField_65.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_65.Value = '1';
            app.EditField_66 = uieditfield(app.UIFigure, 'text');
            app.EditField_66.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_66.Value = '1';
            app.EditField_67 = uieditfield(app.UIFigure, 'text');
            app.EditField_67.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_67.Value = '1';
            app.EditField_68 = uieditfield(app.UIFigure, 'text');
            app.EditField_68.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_68.Value = '1';
            app.EditField_69 = uieditfield(app.UIFigure, 'text');
            app.EditField_69.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_69.Value = '1';
            app.EditField_71 = uieditfield(app.UIFigure, 'text');
            app.EditField_71.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_71.Value = '1';
            app.EditField_72 = uieditfield(app.UIFigure, 'text');
            app.EditField_72.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_72.Value = '1';
            app.EditField_73 = uieditfield(app.UIFigure, 'text');
            app.EditField_73.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_73.Value = '1';
            app.EditField_74 = uieditfield(app.UIFigure, 'text');
            app.EditField_74.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_74.Value = '1';
            app.EditField_75 = uieditfield(app.UIFigure, 'text');
            app.EditField_75.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_75.Value = '1';
            app.EditField_76 = uieditfield(app.UIFigure, 'text');
            app.EditField_76.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_76.Value = '1';
            app.EditField_77 = uieditfield(app.UIFigure, 'text');
            app.EditField_77.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_77.Value = '1';
            app.EditField_78 = uieditfield(app.UIFigure, 'text');
            app.EditField_78.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_78.Value = '1';
            app.EditField_79 = uieditfield(app.UIFigure, 'text');
            app.EditField_79.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_79.Value = '1';
            app.EditField_81 = uieditfield(app.UIFigure, 'text');
            app.EditField_81.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_81.Value = '1';
            app.EditField_82 = uieditfield(app.UIFigure, 'text');
            app.EditField_82.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_82.Value = '1';
            app.EditField_83 = uieditfield(app.UIFigure, 'text');
            app.EditField_83.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_83.Value = '1';
            app.EditField_84 = uieditfield(app.UIFigure, 'text');
            app.EditField_84.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_84.Value = '1';
            app.EditField_85 = uieditfield(app.UIFigure, 'text');
            app.EditField_85.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_85.Value = '1';
            app.EditField_86 = uieditfield(app.UIFigure, 'text');
            app.EditField_86.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_86.Value = '1';
            app.EditField_87 = uieditfield(app.UIFigure, 'text');
            app.EditField_87.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_87.Value = '1';
            app.EditField_88 = uieditfield(app.UIFigure, 'text');
            app.EditField_88.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_88.Value = '1';
            app.EditField_89 = uieditfield(app.UIFigure, 'text');
            app.EditField_89.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_89.Value = '1';
            app.EditField_91 = uieditfield(app.UIFigure, 'text');
            app.EditField_91.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_91.Value = '1';
            app.EditField_92 = uieditfield(app.UIFigure, 'text');
            app.EditField_92.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_92.Value = '1';
            app.EditField_93 = uieditfield(app.UIFigure, 'text');
            app.EditField_93.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_93.Value = '1';
            app.EditField_94 = uieditfield(app.UIFigure, 'text');
            app.EditField_94.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_94.Value = '1';
            app.EditField_95 = uieditfield(app.UIFigure, 'text');
            app.EditField_95.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_95.Value = '1';
            app.EditField_96 = uieditfield(app.UIFigure, 'text');
            app.EditField_96.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_96.Value = '1';
            app.EditField_97 = uieditfield(app.UIFigure, 'text');
            app.EditField_97.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_97.Value = '1';
            app.EditField_98 = uieditfield(app.UIFigure, 'text');
            app.EditField_98.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_98.Value = '1';
            app.EditField_99 = uieditfield(app.UIFigure, 'text');
            app.EditField_99.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EditField_99.Value = '1';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Solve_sudoku, true);
            app.Button.Text = '求解';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @CleanAll, true);
            app.Button_2.Text = '清空';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end